classdef drawtonum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RecognizeDrawingButton   matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        SelectPhotoSpinner       matlab.ui.control.Spinner
        SelectPhotoSpinnerLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imds % loaded images
        net % NN
        selectedImage = 1 % n of selected image
        freehand
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            digitDatasetPath = fullfile(matlabroot,'toolbox','nnet','nndemos', ...
    'nndatasets','DigitDataset');
             

            app.imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            nNetFile = 'nnet.mat';
            if isfile(nNetFile)
                load(nNetFile, 'network')
                app.net = network;
            else
                numTrainFiles = 750;
                [imdsTrain,imdsValidation] = splitEachLabel(app.imds,numTrainFiles,'randomize');
    
                inputSize = [28 28 1];
                numClasses = 10;
                
                layers = [
                    imageInputLayer(inputSize)
                    convolution2dLayer(5,20)
                    batchNormalizationLayer
                    reluLayer
                    fullyConnectedLayer(numClasses)
                    softmaxLayer
                    classificationLayer];
    
                options = trainingOptions('sgdm', ...
                    'MaxEpochs',4, ...
                    'ValidationData',imdsValidation, ...
                    'ValidationFrequency',30, ...
                    'Verbose',false, ...
                    'Plots','training-progress');
    
                
                app.net = trainNetwork(imdsTrain,layers,options);
                network = app.net;
                save(nNetFile, 'network');
            end


            img = readimage(app.imds,1);
            imshow(img, 'Parent', app.UIAxes);
            n = classify(app.net, img);
            app.Label.Text = n;

            app.SelectPhotoSpinner.Limits = [0 9999];

            ax=app.UIAxes;
            ylim(ax, [0,30]);
            xlim(ax, [0,30]);

            disableDefaultInteractivity(app.UIAxes)
        end

        % Value changed function: SelectPhotoSpinner
        function SelectPhotoSpinnerValueChanged(app, event)
            value = app.SelectPhotoSpinner.Value;
            app.selectedImage = value + 1;
            image = readimage(app.imds, value + 1);
            imshow(image, 'Parent', app.UIAxes);
            n = classify(app.net, image);
            app.Label.Text = n;
        end

        % Value changing function: SelectPhotoSpinner
        function SelectPhotoSpinnerValueChanging(app, event)
            value = event.Value;
            image = readimage(app.imds, value + 1);
            imshow(image, 'Parent', app.UIAxes);
        end

        % Button pushed function: RecognizeDrawingButton
        function RecognizeDrawingButtonPushed(app, event)
            
            imshow(zeros(28), 'Parent', app.UIAxes);
            area = drawfreehand(app.UIAxes, ...
                'color', 'white', ...
                'Closed', false, ...
                'DrawingArea', [0,0,28,28], ...
                'LineWidth', 10);
            mask = area.createMask(28,28) * 255;
            mask = imdilate(mask, true(2));
            app.freehand = mask;
            imshow(mask, 'Parent', app.UIAxes);

            n = classify(app.net, mask);
            app.Label.Text = n;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Draw')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.SelectPhotoSpinnerLabel = uilabel(app.UIFigure);
            app.SelectPhotoSpinnerLabel.Text = 'Select Photo';
            app.SelectPhotoSpinner = uispinner(app.UIFigure);
            app.SelectPhotoSpinner.ValueChangingFcn = createCallbackFcn(app, @SelectPhotoSpinnerValueChanging, true);
            app.SelectPhotoSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectPhotoSpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '?';
            app.RecognizeDrawingButton = uibutton(app.UIFigure, 'push');
            app.RecognizeDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeDrawingButtonPushed, true);
            app.RecognizeDrawingButton.Text = 'Recognize Drawing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawtonum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end